classdef GUI_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PatientNrEditField         matlab.ui.control.NumericEditField
        PatientNrEditFieldLabel    matlab.ui.control.Label
        PredictButton              matlab.ui.control.Button
        CalibrateButton            matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        PredictionsPanel           matlab.ui.container.Panel
        PaCO2mmHgEditField_2       matlab.ui.control.NumericEditField
        PaCO2mmHgEditField_2Label  matlab.ui.control.Label
        PaO2mmHgEditField_2        matlab.ui.control.NumericEditField
        PaO2mmHgEditField_2Label   matlab.ui.control.Label
        VentilatorSettingsandmodelparamtersPanel  matlab.ui.container.Panel
        fA100EditField             matlab.ui.control.NumericEditField
        fA100EditFieldLabel        matlab.ui.control.Label
        fs100EditField             matlab.ui.control.NumericEditField
        fs100EditFieldLabel        matlab.ui.control.Label
        MVlminEditField            matlab.ui.control.NumericEditField
        MVlminEditFieldLabel       matlab.ui.control.Label
        FiO2EditField              matlab.ui.control.NumericEditField
        FiO2Label                  matlab.ui.control.Label
        PatientDataPanel           matlab.ui.container.Panel
        PaCO2mmHgEditField         matlab.ui.control.NumericEditField
        PaCO2mmHgEditFieldLabel    matlab.ui.control.Label
        PaO2mmHgEditField          matlab.ui.control.NumericEditField
        PaO2mmHgEditFieldLabel     matlab.ui.control.Label
        HbglEditField              matlab.ui.control.NumericEditField
        HbglEditFieldLabel         matlab.ui.control.Label
        pHEditField                matlab.ui.control.NumericEditField
        pHEditFieldLabel           matlab.ui.control.Label
        QlminEditField             matlab.ui.control.NumericEditField
        QlminEditFieldLabel        matlab.ui.control.Label
        TempCEditField             matlab.ui.control.NumericEditField
        TempCEditFieldLabel        matlab.ui.control.Label
        RQ100EditField             matlab.ui.control.NumericEditField
        RQ100EditFieldLabel        matlab.ui.control.Label
        dVCO2mlminEditField        matlab.ui.control.NumericEditField
        dVCO2mlminEditFieldLabel   matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName,pathName] = uigetfile('.xlsx','Select Patient file');
            filePath = fullfile(pathName,fileName);
            app.PatientNrEditField.Value = xlsread(filePath, 'Patient_Data','B1');
            app.data = readtable(filePath);
            app.CalibrateButton.Enable = "on";
            app.QlminEditField.Value = app.data.Q_l_min_;
            app.dVCO2mlminEditField.Value = app.data.dVCO2_ml_min_/1000;
            app.RQ100EditField.Value = app.data.RQ___100_;
            app.TempCEditField.Value = app.data.Temp__C_;
            app.HbglEditField.Value = app.data.Hb_g_dl_*10;
            app.pHEditField.Value = app.data.pH;
            app.PaO2mmHgEditField.Value = app.data.PaO2_mmHg_;
            app.PaCO2mmHgEditField.Value = app.data.PaCO2_mmHg_;
            app.FiO2EditField.Value = xlsread(filePath, 'Ventilator_settings','B2');
            app.MVlminEditField.Value = xlsread(filePath, 'Ventilator_settings','A2');
            hold(app.UIAxes,"on")
            plot(app.UIAxes,app.FiO2EditField.Value,app.PaO2mmHgEditField.Value,'Color','r',Marker='o',MarkerSize=8,MarkerEdgeColor='r',LineWidth=2)
            legend(app.UIAxes,'recorded PaO2')
            grid(app.UIAxes,"on")
            hold(app.UIAxes2,"on")
            plot(app.UIAxes2,app.MVlminEditField.Value,app.PaCO2mmHgEditField.Value,'Color','b',Marker='o',MarkerSize=8,MarkerEdgeColor='b',LineWidth=2)
            legend(app.UIAxes2,'recorded PaCO2')
            grid(app.UIAxes2,"on")


        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            dVCO2 = app.dVCO2mlminEditField.Value;
MV = app.MVlminEditField.Value;
RQ = app.RQ100EditField.Value;
FiO2 = app.FiO2EditField.Value/100;
Q = app.QlminEditField.Value;
Temp = app.TempCEditField.Value;
pH = app.pHEditField.Value;
Hb = app.HbglEditField.Value*10;
initial_guess = [0.4 0.1];
PaO2_r = app.PaO2mmHgEditField.Value;
PaCO2_r = app.PaCO2mmHgEditField.Value;

x = fminsearch(@(x) SSE_Task_2(x,PaO2_r,PaCO2_r,dVCO2,MV,RQ,FiO2,Q,Temp,pH,Hb),initial_guess);
fA = x(1);
fs = x(2);
app.fA100EditField.Value = fA;
app.fs100EditField.Value = fs;
            plot(app.UIAxes,app.FiO2EditField.Value,app.PaO2mmHgEditField.Value,'Color','k',Marker='+',MarkerSize=8,MarkerEdgeColor='k',LineWidth=2)
            legend(app.UIAxes,'calibrated PaO2')
            grid(app.UIAxes,"on")
            plot(app.UIAxes2,app.MVlminEditField.Value,app.PaCO2mmHgEditField.Value,'Color','k',Marker='+',MarkerSize=8,LineWidth=2,MarkerEdgeColor='k')
            legend(app.UIAxes2,'calibrated PaCO2')
            grid(app.UIAxes2,"on")
            app.PredictButton.Enable = "on";


        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
           
dVCO2 = app.dVCO2mlminEditField.Value;
MV = app.MVlminEditField.Value;
RQ = app.RQ100EditField.Value;
FiO2 = app.FiO2EditField.Value/100;
Q = app.QlminEditField.Value;
Temp = app.TempCEditField.Value;
pH = app.pHEditField.Value;
Hb = app.HbglEditField.Value*10;
initial_guess = [0.4 0.1];
PaO2_r = app.PaO2mmHgEditField.Value;
PaCO2_r = app.PaCO2mmHgEditField.Value;
fA = app.fA100EditField.Value;
fs = app.fs100EditField.Value

[PaO2, PaCO2] = Rileymodel(dVCO2,MV,RQ,fA,fs,FiO2,Q,Temp,pH,Hb);
app.PaO2mmHgEditField_2.Value = PaO2;
app.PaCO2mmHgEditField_2.Value = PaCO2;
cla(app.UIAxes);
cla(app.UIAxes2);
hold(app.UIAxes,"on")
hold(app.UIAxes2,"on")
            plot(app.UIAxes,app.FiO2EditField.Value,app.PaO2mmHgEditField_2.Value,'Color','r',Marker='+',MarkerSize=8,MarkerEdgeColor='r',LineWidth=2)
            legend(app.UIAxes,'predicted PaO2')
            grid(app.UIAxes,"on")
            plot(app.UIAxes2,app.MVlminEditField.Value,app.PaCO2mmHgEditField_2.Value,'Color','b',Marker='+',MarkerSize=8,LineWidth=2,MarkerEdgeColor='b')
            legend(app.UIAxes2,'predicted PaCO2')
            grid(app.UIAxes2,"on")


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PaO2')
            xlabel(app.UIAxes, 'FiO2 [%]')
            ylabel(app.UIAxes, 'PaO2 [mmHg]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PaCO2')
            xlabel(app.UIAxes2, 'MV [l/min]')
            ylabel(app.UIAxes2, 'PaCO2 [mmHg]')
            zlabel(app.UIAxes2, 'Z')
            app.PatientDataPanel = uipanel(app.UIFigure);
            app.PatientDataPanel.Title = 'Patient Data';
            app.dVCO2mlminEditFieldLabel = uilabel(app.PatientDataPanel);
            app.dVCO2mlminEditFieldLabel.Text = 'dVCO2 [ml/min]';
            app.dVCO2mlminEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RQ100EditFieldLabel = uilabel(app.PatientDataPanel);
            app.RQ100EditFieldLabel.Text = 'RQ [%/100]';
            app.RQ100EditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.TempCEditFieldLabel = uilabel(app.PatientDataPanel);
            app.TempCEditFieldLabel.Text = 'Temp [°C]';
            app.TempCEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.QlminEditFieldLabel = uilabel(app.PatientDataPanel);
            app.QlminEditFieldLabel.Text = 'Q [l/min]';
            app.QlminEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.pHEditFieldLabel = uilabel(app.PatientDataPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.HbglEditFieldLabel = uilabel(app.PatientDataPanel);
            app.HbglEditFieldLabel.Text = 'Hb [g/l]';
            app.HbglEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.PaO2mmHgEditFieldLabel = uilabel(app.PatientDataPanel);
            app.PaO2mmHgEditFieldLabel.Text = 'PaO2 [mmHg]';
            app.PaO2mmHgEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.PaCO2mmHgEditFieldLabel = uilabel(app.PatientDataPanel);
            app.PaCO2mmHgEditFieldLabel.Text = 'PaCO2 [mmHg]';
            app.PaCO2mmHgEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.VentilatorSettingsandmodelparamtersPanel = uipanel(app.UIFigure);
            app.VentilatorSettingsandmodelparamtersPanel.Title = 'Ventilator Settings and model paramters';
            app.FiO2Label = uilabel(app.VentilatorSettingsandmodelparamtersPanel);
            app.FiO2Label.Text = 'FiO2 [%]';
            app.FiO2EditField = uieditfield(app.VentilatorSettingsandmodelparamtersPanel, 'numeric');
            app.MVlminEditFieldLabel = uilabel(app.VentilatorSettingsandmodelparamtersPanel);
            app.MVlminEditFieldLabel.Text = 'MV [l/min]';
            app.MVlminEditField = uieditfield(app.VentilatorSettingsandmodelparamtersPanel, 'numeric');
            app.fs100EditFieldLabel = uilabel(app.VentilatorSettingsandmodelparamtersPanel);
            app.fs100EditFieldLabel.Text = 'fs [%/100]';
            app.fs100EditField = uieditfield(app.VentilatorSettingsandmodelparamtersPanel, 'numeric');
            app.fA100EditFieldLabel = uilabel(app.VentilatorSettingsandmodelparamtersPanel);
            app.fA100EditFieldLabel.Text = 'fA [%/100]';
            app.fA100EditField = uieditfield(app.VentilatorSettingsandmodelparamtersPanel, 'numeric');
            app.PredictionsPanel = uipanel(app.UIFigure);
            app.PredictionsPanel.Title = 'Predictions';
            app.PaO2mmHgEditField_2Label = uilabel(app.PredictionsPanel);
            app.PaO2mmHgEditField_2Label.Text = 'PaO2 [mmHg]';
            app.PaO2mmHgEditField_2 = uieditfield(app.PredictionsPanel, 'numeric');
            app.PaCO2mmHgEditField_2Label = uilabel(app.PredictionsPanel);
            app.PaCO2mmHgEditField_2Label.Text = 'PaCO2 [mmHg]';
            app.PaCO2mmHgEditField_2 = uieditfield(app.PredictionsPanel, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.PatientNrEditFieldLabel = uilabel(app.UIFigure);
            app.PatientNrEditFieldLabel.Text = 'Patient Nr.';
            app.PatientNrEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientNrEditField.RoundFractionalValues = 'on';
            app.PatientNrEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end